classdef winSCH < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Grid                            matlab.ui.container.GridLayout
        report_mainGrid                 matlab.ui.container.GridLayout
        search_mainGrid                 matlab.ui.container.GridLayout
        search_nRows                    matlab.ui.control.Label
        search_Suggestions              matlab.ui.control.ListBox
        search_Table                    matlab.ui.control.Table
        search_Metadata                 matlab.ui.control.Label
        search_entryPointPanel          matlab.ui.container.Panel
        search_entryPointGrid           matlab.ui.container.GridLayout
        search_entryPointImage          matlab.ui.control.Image
        search_entryPoint               matlab.ui.control.EditField
        menu_TabGroupGrid               matlab.ui.container.GridLayout
        menu_ControlGrid                matlab.ui.container.GridLayout
        menu_Button4Grid                matlab.ui.container.GridLayout
        menu_Button4Icon                matlab.ui.control.Image
        menu_Button4Label               matlab.ui.control.Label
        menu_Button3Grid                matlab.ui.container.GridLayout
        menu_Button3Icon                matlab.ui.control.Image
        menu_Button3Label               matlab.ui.control.Label
        menu_Button2Grid                matlab.ui.container.GridLayout
        menu_Button2Icon                matlab.ui.control.Image
        menu_Button2Label               matlab.ui.control.Label
        menu_Button1Grid                matlab.ui.container.GridLayout
        menu_Button1Icon                matlab.ui.control.Image
        menu_Button1Label               matlab.ui.control.Label
        menu_Underline                  matlab.ui.control.Image
        menu_TabGroup                   matlab.ui.container.TabGroup
        Tab1                            matlab.ui.container.Tab
        Tab1Grid                        matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        wordCloudRefresh                matlab.ui.control.Image
        wordCloudPanel                  matlab.ui.container.Panel
        notes_Panel2                    matlab.ui.container.Panel
        notes_Panel2Grid                matlab.ui.container.GridLayout
        notes_addImage                  matlab.ui.control.Image
        notes_Value                     matlab.ui.control.TextArea
        notes_ValueLabel                matlab.ui.control.Label
        notes_Attribute                 matlab.ui.control.DropDown
        notes_AttributeLabel            matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        annotationPanelVisibility       matlab.ui.control.Image
        wordCloudVisibility             matlab.ui.control.Image
        notes_Panel1                    matlab.ui.container.Panel
        notes_Panel1Grid                matlab.ui.container.GridLayout
        notes_ProductInfo               matlab.ui.control.HTML
        notes_Label1                    matlab.ui.control.Label
        Tab2                            matlab.ui.container.Tab
        Tab2Grid                        matlab.ui.container.GridLayout
        filter_SecundaryPanel           matlab.ui.container.Panel
        filter_SecundaryGrid            matlab.ui.container.GridLayout
        filter_Status                   matlab.ui.control.DropDown
        filter_StatusCheckBox           matlab.ui.control.CheckBox
        filter_TradeName                matlab.ui.control.EditField
        filter_TradeNameCheckBox        matlab.ui.control.CheckBox
        filter_Model                    matlab.ui.control.EditField
        filter_ModelCheckBox            matlab.ui.control.CheckBox
        filter_Manufacturer             matlab.ui.control.EditField
        filter_ManufacturerCheckBox     matlab.ui.control.CheckBox
        filter_CNPJ                     matlab.ui.control.EditField
        filter_CNPJCheckBox             matlab.ui.control.CheckBox
        filter_Applicant                matlab.ui.control.EditField
        filter_ApplicantCheckBox        matlab.ui.control.CheckBox
        filter_ProductList              matlab.ui.control.DropDown
        filter_ProductListCheckBox      matlab.ui.control.CheckBox
        filter_Certification            matlab.ui.control.EditField
        filter_CertificationCheckBox    matlab.ui.control.CheckBox
        filter_SecundaryPanelLabel      matlab.ui.control.Label
        filter_PrimaryPanel             matlab.ui.container.ButtonGroup
        filter_PrimaryOption3           matlab.ui.control.RadioButton
        filter_PrimaryOption2           matlab.ui.control.RadioButton
        filter_PrimaryOption1           matlab.ui.control.RadioButton
        filter_PrimaryPanelLabel        matlab.ui.control.Label
        Tab3                            matlab.ui.container.Tab
        Tab3Grid                        matlab.ui.container.GridLayout
        report_Tree                     matlab.ui.container.Tree
        report_addImage                 matlab.ui.control.Image
        report_SelectedProductPanel     matlab.ui.container.Panel
        report_SelectedProductGrid      matlab.ui.container.GridLayout
        report_Type_2                   matlab.ui.control.DropDown
        report_TypeLabel_2              matlab.ui.control.Label
        report_ComplementaryNotes       matlab.ui.control.TextArea
        report_ComplementaryNotesLabel  matlab.ui.control.Label
        report_MonetaryValue            matlab.ui.control.NumericEditField
        report_MonetaryValueLabel       matlab.ui.control.Label
        report_Quantity                 matlab.ui.control.NumericEditField
        report_QuantityLabel            matlab.ui.control.Label
        report_Type                     matlab.ui.control.DropDown
        report_TypeLabel                matlab.ui.control.Label
        report_SelectedProductLabel     matlab.ui.control.Label
        report_TreeLabel                matlab.ui.control.Label
        Tab4                            matlab.ui.container.Tab
        Tab4Grid                        matlab.ui.container.GridLayout
        config_Panel                    matlab.ui.container.Panel
        config_PanelGrid                matlab.ui.container.GridLayout
        notes_WordCloudPanel            matlab.ui.container.Panel
        notes_WordCloudGrid             matlab.ui.container.GridLayout
        notes_WordCloudColumn           matlab.ui.control.DropDown
        notes_WordCloudColumnLabel      matlab.ui.control.Label
        notes_WordCloudNumber           matlab.ui.control.DropDown
        notes_WordCloudNumberLabel      matlab.ui.control.Label
        notes_WordCloudLabel            matlab.ui.control.Label
        config_htmlListView_2           matlab.ui.control.CheckBox
        config_SuggestionPanel          matlab.ui.container.Panel
        config_SuggestionGrid           matlab.ui.container.GridLayout
        config_nMinWords                matlab.ui.control.DropDown
        config_nMinWordsLabel           matlab.ui.control.Label
        config_nCharacters              matlab.ui.control.Spinner
        config_nCharactersLabel         matlab.ui.control.Label
        config_SuggestionLabel          matlab.ui.control.Label
        config_FolderGrid               matlab.ui.container.GridLayout
        config_FolderButton             matlab.ui.control.Button
        config_Folder                   matlab.ui.control.DropDown
        config_FolderLabel              matlab.ui.control.Label
        config_Label                    matlab.ui.control.Label
        menu_Grid                       matlab.ui.container.GridLayout
        jsBackDoor                      matlab.ui.control.HTML
        menu_btnInfo                    matlab.ui.control.Image
        menu_btnReport                  matlab.ui.control.Image
        menu_btnSearch                  matlab.ui.control.Image
        menu_btnSettings                matlab.ui.control.Image
        menu_Title                      matlab.ui.control.Label
    end

    
    properties (Access = public)
        %-----------------------------------------------------------------%
        CallingApp
        General

        rootFolder
        userPath

        timerObj

        rawDataTable
        releasedData
        cacheData

        annotationTable
        annotationTempTable
        
        previousSearch   = ''
        previousItemData = 0

        wordCloudChart
    end

    
    methods (Access = private)
        %-----------------------------------------------------------------%
        function TimerCreation(app)
            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.TimerFcn);
            start(app.timerObj)
        end


        %-----------------------------------------------------------------%
        function TimerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)
                
                jsBackDoor_Customizations(app)
                drawnow
                
                ReadFiles(app)
                fcn.parpoolCheck()
                
                app.search_entryPoint.Enable = 1;
            end
        end
        

        %-----------------------------------------------------------------%
        function ReadFiles(app)
            % app.General
            app.General = jsondecode(fileread(fullfile(app.rootFolder, 'Settings', 'GeneralSettings.json')));

            % app.rawDataTable
            % app.releaseData
            % app.cacheData
            computerName = getenv('COMPUTERNAME');
            switch computerName
                case app.General.webApp.computerName
                    appMode    = 'webApp';
                    fileFolder = getenv('USERPROFILE');
                    filePath   = app.General.webApp.filePath;
                    fileName   = app.General.webApp.fileName;

                otherwise
                    appMode    = 'desktopApp';
                    fileFolder = app.rootFolder;
                    filePath   = app.General.desktopApp.filePath;
                    fileName   = app.General.desktopApp.fileName;
            end
            fileFullPath = fullfile(fileFolder, filePath, fileName);
            
            [app.rawDataTable, ...
             app.releasedData, ...
             app.cacheData,    ...
             matFullFile] = fcn.ReadSCHTable(fileFullPath, class.Constants.cacheColumns);

            if ~isempty(matFullFile)
                [~, fileMATName] = fileparts(fileName);
                fileMATName      = [fileMATName '.mat'];

                eval(sprintf('app.General.%s.fileName = ''%s'';', appMode, fileMATName))
                fcn.updateFile_GeneralSettings(app.General, app.rootFolder)
            end

            % app.annotationTable
            % app.annotationTempTable
            annotationFileFullPath      = fullfile(fileFolder, filePath, app.General.annotation.fileName);
            annotationTempFileFullPath  = fullfile(fileFolder, filePath, app.General.annotation.tempFileName);


            % PRECISO EVOLUIR ISSO AQUI, DEFININDO ONDE FICARÃO OS ARQUIVOS 
            msgError = {};
            try
                app.annotationTable     = class.Annotation.read('Local', annotationFileFullPath);
            catch ME
                msgError{end+1}         = ['annotationTable: ' ME.message];
                app.annotationTable     = class.Annotation.AnnotationTable();                
            end

            try
                app.annotationTempTable = class.Annotation.read('Local', annotationTempFileFullPath);
            catch ME
                msgError{end+1}         = ['annotationTempTable: ' ME.message];
                app.annotationTempTable = class.Annotation.AnnotationTable();                
            end

            [app.annotationTable, ...
             app.annotationTempTable]   = class.Annotation.KeepJustOneWordCloud(app.annotationTable, app.annotationTempTable);
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor.HTMLSource           = sprintf('<!DOCTYPE html>\n<html>\n<head>\n<script>\n%s\n</script>\n</head>\n\n<body>\n</body>\n</html>', fileread(fullfile(ccTools.Path, 'css&js', 'compCustomization.js')));
            app.jsBackDoor.HTMLEventReceivedFcn = @(~, evt)jsBackDoor_keyDownListener(app, evt);
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app)
            % Cria keydown listeners, em JS, para os componentes descritos
            % em objList.
            objList = {app.search_entryPoint, app.search_Suggestions};
            for ii = 1:numel(objList)
                objList{ii}.UserData = struct(objList{ii}).Controller.ViewModel.Id;
            end            
            sendEventToHTMLSource(app.jsBackDoor, "addKeyDownListener", struct('DataName', 'app.search_entryPoint',  'DataTag', app.search_entryPoint.UserData));
            sendEventToHTMLSource(app.jsBackDoor, "addKeyDownListener", struct('DataName', 'app.search_Suggestions', 'DataTag', app.search_Suggestions.UserData));

            % Força a exclusão da tela de progresso da inicialização de um 
            % webapp, caso aplicável.
            sendEventToHTMLSource(app.jsBackDoor, "delProgressDialog");

            % Aplica customizações estéticas ao(s) componente(s).
            ccTools.compCustomizationV2(app.jsBackDoor, app.search_entryPoint, 'borderWidth', '0px');
            ccTools.compCustomizationV2(app.jsBackDoor, app.GridLayout2, 'backgroundColor', 'transparent')
            ccTools.compCustomizationV2(app.jsBackDoor, app.GridLayout3, 'backgroundColor', 'transparent')            
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_keyDownListener(app, event)

            % Foi adicionado o evento JS-keydown das teclas ["ArrowUp", "ArrowDown", "Enter", "Escape"]
            % aos componentes app.search_entryPoint (matlab.ui.control.EditField) e app.search_Suggestions
            % (matlab.ui.control.ListBox) usando o JS-backdoor app.jsBackDoor (matlab.ui.control.HTML).
    
            % Em relação aos callbacks configuráveis no próprio MATLAB:
            % - matlab.ui.control.EditField 
            %   (a) Possui os eventos "ValueChangedFcn" e "ValueChangingFcn".
            %   (b) Não responde à tecla "Escape".
            %   (c) Responde às teclas "ArrowUp" e "ArrowDown", controlando a posição 
            %       do cursor (início e fim, respectivamente)
            %   (d) Reponde à tecla "Enter", executando "ValueChangingFcn" e "ValueChangedFcn", 
            %       nesse ordem.
    
            % - matlab.ui.control.ListBox
            %   (a) possui os eventos "ValueChangedFcn", "ClickedFcn" e "DoubleClickedFcn".
            %   (b) Responde às teclas "ArrowUp" e "ArrowDown", executando "ValueChangedFcn", 
            %       desde que não estejam selecionadas as suas "bordas" (valor 1 e "ArrowUp", 
            %       ou valor n e "ArrowDown", por exemplo).
    
            % Num eventual clique de uma das teclas ["ArrowUp", "ArrowDown", "Enter", "Escape"],
            % o trigger do evento JS-keydown ocorre antes do trigger dos eventos padrões dos 
            % componentes matlab.ui.control.EditField e matlab.ui.control.ListBox.
    
            % Isso é bom, mas ruim por criar uma complexidade extra! :(
    
            % Quando altero o conteúdo de app.search_entryPoint, sem alterar o seu foco, será executado 
            % o evento "ValueChangingFcn". Se pressiono a tecla "Enter", será executada essa função 
            % (jsBackDoor_keyDownListener) antes de atualizar a propriedade "Value" do app.search_entryPoint.
    
            % Por conta disso, é essencial inserir waitfor(app.search_entryPoint, 'Value')

            switch event.HTMLEventData
                case 'app.search_entryPoint'
                    focus(app.jsBackDoor)

                    switch event.HTMLEventName
                        case 'Escape'
                            set(app.search_Suggestions, Visible=0, Value={})

                            suggestion_CheckIfEntryPointNeedsUpdate(app)
                            if numel(app.search_entryPoint.Value) <= 1
                                set(app.search_entryPoint, 'FontColor', [.8,.8,.8], 'Value', 'O que você quer pesquisar?')
                            end

                        otherwise
                            % Ao trocar o foco de app.search_entryPoint para app.jsBackDoor, o MATLAB executa 
                            % logicamente a operação: app.search_entryPoint.Value = app.search_entryPoint.ChangingValue
                            % Ao inserir waitfor, teremos a garantia de usar o valor atual do objeto...
                            suggestion_CheckIfEntryPointNeedsUpdate(app)
                            if numel(app.search_entryPoint.Value) <= 1
                                sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));

                            else
                                switch event.HTMLEventName
                                    case 'ArrowDown'
                                        app.previousItemData = 1;
            
                                        set(app.search_Suggestions, 'Visible', 1, 'Value', 1)
                                        scroll(app.search_Suggestions, "top")
                                        focus(app.search_Suggestions)
                    
                                    case 'ArrowUp'
                                        nMaxValues = numel(app.search_Suggestions.Items);

                                        app.previousItemData = nMaxValues;            
                                        set(app.search_Suggestions, 'Visible', 1, 'Value', nMaxValues)
                                        scroll(app.search_Suggestions, "bottom")
                                        focus(app.search_Suggestions)
                    
                                    case 'Enter'                                        
                                        set(app.search_Suggestions, Visible=0, Value={})
                                        EntryPointImageClicked(app)
                                        % sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));
                                end
                            end
                    end

                %---------------------------------------------------------%
                case 'app.search_Suggestions'
                    switch event.HTMLEventName
                        case 'ArrowDown'
                            nMaxValues = numel(app.search_Suggestions.Items);

                            if (app.previousItemData == nMaxValues) && (app.search_Suggestions.Value == nMaxValues)
                                app.previousItemData = 0;
                                
                                set(app.search_Suggestions, Visible=0, Value={})
                                sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));
                            else
                                app.previousItemData = app.search_Suggestions.Value;
                            end
        
                        case 'ArrowUp'
                            if (app.previousItemData == 1) && (app.search_Suggestions.Value == 1)
                                app.previousItemData = 0;

                                set(app.search_Suggestions, Visible=0, Value={})
                                sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));
                            else
                                app.previousItemData = app.search_Suggestions.Value;
                            end
        
                        case 'Enter'
                            eventValue = app.search_Suggestions.Items{app.search_Suggestions.Value};

                            app.search_entryPoint.Value = eventValue;
                            suggestion_EntryPointValueChanging(app, eventValue, false)
                            
                            sendEventToHTMLSource(app.jsBackDoor, "EditFieldFocus", struct('DataName', 'app.search_entryPoint', 'DataTag', app.search_entryPoint.UserData));

                        case 'Escape'
                            set(app.search_Suggestions, Visible=0, Value={})
                    end
            end
            drawnow
        end


        %-----------------------------------------------------------------%
        function jsBackDoor_HTMLEventReceivedFcn(app, event)
            switch event.Source
                case app.search_List
                    % app.notes_ProductInfo.HTMLSource = event.HTMLEventData;
            end
        end


        %-----------------------------------------------------------------%
        function cacheColumnName = suggestion_FindCache_ColumnName(app, columnName)
            cacheColumnName = sprintf('_%s', columnName);
            if ~ismember(cacheColumnName, app.rawDataTable.Properties.VariableNames)
                cacheColumnName = columnName;
            end
        end


        %-----------------------------------------------------------------%
        function cacheStringList = suggestion_FindCache_StringListOfSelectedColumn(app, listOfColumns)
            cacheIndex      = find(strcmp(listOfColumns, {app.cacheData.Column}), 1);
            cacheStringList = app.cacheData(cacheIndex).uniqueValues;
        end


        %-----------------------------------------------------------------%
        function cacheTokenList = suggestion_FindCache_TokenListOfSelectedColumn(app, listOfColumns)
            cacheIndex     = find(strcmp(listOfColumns, {app.cacheData.Column}), 1);
            cacheTokenList = app.cacheData(cacheIndex).uniqueTokens;
        end


        %-----------------------------------------------------------------%
        function suggestion_EntryPointValueChanging(app, eventValue, panelVisibility)
            value2Search = fcn.PreProcessedData(eventValue);

            if numel(value2Search) <= 1
                set(app.search_Suggestions, Visible=0, Items={}, ItemsData=[])
                set(app.search_entryPointImage, Enable=0)
            else
                set(app.search_entryPointImage, Enable=1)
                if ~strcmp(eventValue, app.previousSearch)
                    suggestion_Algorithm(app, value2Search, panelVisibility)                    
                end
            end

            app.previousSearch = eventValue;
        end


        %-----------------------------------------------------------------%
        function suggestion_CheckIfEntryPointNeedsUpdate(app)
            if ~strcmp(app.search_entryPoint.Value, app.previousSearch)
                waitfor(app.search_entryPoint, 'Value')
            end
        end


        %-----------------------------------------------------------------%
        function suggestion_Algorithm(app, value2Search, panelVisibility)
            listOfColumns   = primaryFilter_ListOfColumns(app);
            cacheStringList = suggestion_FindCache_StringListOfSelectedColumn(app, listOfColumns);
            cacheTokenList  = [];

            nMaxValues      = class.Constants.nMaxSuggested;
            
            algorithmsOrder = {'startsWith', 'Contains', 'Levenshtein'};
            redFontFlag     = false;

            idxFiltered     = [];
            for kk = algorithmsOrder
                methodName = char(kk);
                
                if strcmp(methodName, 'Levenshtein')
                    if numel(idxFiltered) >= nMaxValues
                        break
                    end

                    if isempty(idxFiltered)
                        redFontFlag = true;
                    end

                    cacheTokenList = suggestion_FindCache_TokenListOfSelectedColumn(app, listOfColumns);
                end

                idxFiltered = fcn.getSimilarStrings(methodName, cacheStringList, cacheTokenList, value2Search, idxFiltered, nMaxValues);
            end

            set(app.search_Suggestions, 'Visible', panelVisibility, 'Value', {}, 'Items', cacheStringList(idxFiltered), 'ItemsData', 1:numel(idxFiltered))
            layout_EntryPointColor(app, redFontFlag)
        end


        %-----------------------------------------------------------------%
        function [selectedRow, selectedHom, selectedHomIndex] = notes_SelectedRow(app)
            selectedRow = app.search_Table.Selection;

            if ~isempty(selectedRow)
                selectedHom      = char(app.search_Table.Data.("Homologação")(selectedRow));
                selectedHomIndex = strcmp(app.search_Table.Data.("Homologação"), selectedHom);
            else
                selectedHom      = '';
                selectedHomIndex = [];
            end
        end


        %-----------------------------------------------------------------%
        function relatedTable = notes_RelatedCacheTable(app, selectedHom)
            annotationFull    = [app.annotationTable; app.annotationTempTable];
            annotationLogical = strcmp(annotationFull.("Homologação"), selectedHom);
            relatedTable      = annotationFull(annotationLogical, :);
        end


        %-----------------------------------------------------------------%
        function notes_Add2Cache(app, selectedHom, attributeName, attributeValue, ForceAdd2Cache)
            newRowTable = table({char(matlab.lang.internal.uuid())},   ...
                                {datestr(now, 'dd/mm/yyyy HH:MM:SS')}, ...
                                {getenv("COMPUTERNAME")},              ...
                                {getenv("USERNAME")},                  ...
                                {selectedHom},                         ...
                                {attributeName},                       ...
                                {attributeValue}, 'VariableNames', class.Constants.notesColumns);

            [annotationFlag, ...
             relatedTable,   ...
             app.annotationTempTable] = class.Annotation.addRow(app.annotationTable, app.annotationTempTable, newRowTable, ForceAdd2Cache);

            if annotationFlag
                selectedHomIndex = find(strcmp(app.rawDataTable.("Homologação"), selectedHom));
                app.notes_ProductInfo.HTMLSource = fcn.htmlCode_rowTableInfo(app.rawDataTable(selectedHomIndex, :), relatedTable);

                % Ajusta apenas o estilo de anotação do registro...
                layout_TableRemoveStyle(app, 'cell')
                layout_TableAnnotationIcon(app)
                
            else
                layout.modalWindow(app.UIFigure, 'warning', sprintf('Conjunto atributo/valor já consta como anotação do registro %s.', selectedHom));
            end
        end


        %-----------------------------------------------------------------%
        function listOfColumns = primaryFilter_ListOfColumns(app)
            selectedButton = app.filter_PrimaryPanel.SelectedObject;
            listOfColumns  = selectedButton.Text;

            % if app.filter_PrimaryOption4.Value
            %     listOfColumns = [listOfColumns, {'Annotation'}];
            % end
        end


        %-----------------------------------------------------------------%
        function primaryFilter_Filtering(app, words2Search, listOfColumns)
            % Rotina de inicialização dos objetos relacionados aos filtros 
            % secundários.
            set(findobj(app.filter_SecundaryGrid, 'Type', 'uicheckbox'),  'Value', 0,  'Enable', 1)
            set(findobj(app.filter_SecundaryGrid, 'Type', 'uieditfield'), 'Value', '', 'Enable', 0)
            set(findobj(app.filter_SecundaryGrid, 'Type', 'uidropdown'),  'Items', {}, 'Enable', 0)

            % Filtragem primária.
            primaryIndex = [];

            listOfCacheColumns = cellfun(@(x) sprintf('_%s', x), strsplit(listOfColumns, ' | '), 'UniformOutput', false);
            for ii = 1:numel(words2Search)
                primaryIndex = [primaryIndex; find(any(strcmp(app.rawDataTable{:, listOfCacheColumns}, words2Search{ii}), 2))];
            end
            primaryIndex = unique(primaryIndex, 'stable');
            
            set(app.search_Table, 'Data',      app.rawDataTable(primaryIndex, class.Constants.GUIColumns), ...
                                  'UserData',  primaryIndex)
                        
            % Renderiza em tela o número de linhas, além de selecionar a primeira 
            % linha da tabela, caso a pesquisa retorne algo.
            layout_TableNumberOfRows(app)
            layout_TableInitialSelection(app)

            % Aplica estilo na tabela...
            layout_TableAddStyle(app)

            drawnow
        end


        %-----------------------------------------------------------------%
        function layout_OperationModeIconColor(app)
            if app.Grid.ColumnWidth{4}                                      % Search Mode
                app.menu_btnSearch.ImageSource = 'Zoom_36x36Y.png';
                app.menu_btnReport.ImageSource = 'Report_36x36W.png';
            else                                                            % Report Mode
                app.menu_btnSearch.ImageSource = 'Zoom_36x36W.png';
                app.menu_btnReport.ImageSource = 'Report_36x36Y.png';
            end
        end


        %-----------------------------------------------------------------%
        function layout_EntryPointColor(app, redFlag)
            if redFlag
                fontColor = [1,0,0];
            else
                fontColor = [0,0,0];
            end
            app.search_entryPoint.FontColor = fontColor;
            drawnow
        end


        %-----------------------------------------------------------------%
        function layout_TableVisibility(app)
            if ~app.search_Table.Visible
                ccTools.compCustomizationV2(app.jsBackDoor, app.search_Table, 'fontSize', '10px', 'paddingTop', '0px');

                app.search_Table.Visible    = 1;
                app.search_Metadata.Visible = 1;
                app.search_nRows.Visible    = 1;

                menuButtonPushed(app, struct('Source', app.menu_btnSettings))
                app.menu_btnSettings.Visible = 1;
            end
        end


        %-----------------------------------------------------------------%
        function layout_TableNumberOfRows(app)
            nRows = height(app.search_Table.Data);
            app.search_nRows.Text = sprintf('# %d ', nRows);
        end


        %-----------------------------------------------------------------%
        function layout_TableInitialSelection(app)
            if isempty(app.search_Table.Data)
                app.search_Table.Selection = [];
            else
                app.search_Table.Selection = 1;
            end            
            TableSelectionChanged(app)

            focus(app.search_Table)
        end


        %-----------------------------------------------------------------%
        function layout_TableAddStyle(app)
            layout_TableRemoveStyle(app, 'all')

            layout_TableRowStriping(app)
            layout_TablePrimaryColumnBackground(app)
            layout_TableAnnotationIcon(app)
        end


        %-----------------------------------------------------------------%
        function layout_TableRemoveStyle(app, styleType)
            switch styleType
                case 'all'
                    removeStyle(app.search_Table)

                otherwise
                styleTypeIndex  = find(strcmp(cellstr(app.search_Table.StyleConfigurations.Target), styleType));
                if ~isempty(styleTypeIndex)
                    removeStyle(app.search_Table, styleTypeIndex)
                end
            end
        end


        %-----------------------------------------------------------------%
        function layout_TableRowStriping(app)
            [~, ~, uniqueHomIndex] = unique(app.search_Table.Data.("Homologação"), 'stable');
            listOfRows             = find(~mod(uniqueHomIndex, 2));

            addStyle(app.search_Table, class.Constants.tblStyle_row, 'row', listOfRows)
        end
        
        
        %-----------------------------------------------------------------%
        function layout_TablePrimaryColumnBackground(app)
            selectedButton = app.filter_PrimaryPanel.SelectedObject;
            listOfColumns  = strsplit(selectedButton.Text, ' | ');

            addStyle(app.search_Table, class.Constants.tblStyle_col, 'column', listOfColumns)
        end


        %-----------------------------------------------------------------%
        function layout_TableAnnotationIcon(app)
            % Posição da coluna "Homologação".
            homColumnIndex    = find(strcmp(app.search_Table.Data.Properties.VariableNames, 'Homologação'), 1);

            % Valores únicos de homologação e seus índices...
            [listOfHom, ...
             lisOfHomIndex]   = unique(app.search_Table.Data.("Homologação"), 'stable');
            
            % Identifica registros para os quais existe anotação registrada, 
            % aplicando o estilo.
            annotationFull    = [app.annotationTable; app.annotationTempTable];
            annotationLogical = ismember(listOfHom, unique(annotationFull.("Homologação")));
            annotationIndex   = lisOfHomIndex(annotationLogical);
            listOfCells       = [annotationIndex, repmat(homColumnIndex, numel(annotationIndex), 1)];

            addStyle(app.search_Table, class.Constants.tblStyle_cell, "cell", listOfCells)
        end


        %-----------------------------------------------------------------%
        function wordCloud_ChartCreation(app)
            t = tiledlayout(app.wordCloudPanel, 1, 1, 'Padding', 'tight');
            app.wordCloudChart = wordcloud(t, wordCloud_EmptyTable(app), 'Word', 'Count',                  ...
                                           'MaxDisplayWords', str2double(app.notes_WordCloudNumber.Value), ...
                                           'Title', '', 'Units', 'normalized', 'Position', [0,0,1,1]);
        end


        %-----------------------------------------------------------------%
        function emptyTable = wordCloud_EmptyTable(app)
            emptyTable = table('Size',          [0,2],                ...
                               'VariableTypes', {'string', 'double'}, ...
                               'VariableNames', {'Word', 'Count'});
        end


        %-----------------------------------------------------------------%
        function status = wordCloud_CheckCache(app, selectedHom, relatedTable)
            status = false;

            wordCloudLogical = strcmp(relatedTable.("Atributo"), 'WordCloud');
            relatedTable     = relatedTable(wordCloudLogical, :);

            if isempty(relatedTable) || any(wordCloudLogical) && isa(app.wordCloudChart, 'matlab.graphics.chart.WordCloudChart') && ~strcmp(app.wordCloudPanel.Tag, selectedHom)
                status = true;
            end
        end


        %-----------------------------------------------------------------%
        function status = wordCloud_PlotUpdate(app, ForceAdd2Cache)
            status = true;

            [selectedRow, selectedHom] = notes_SelectedRow(app);
            if ~isempty(selectedRow)
                d = layout.modalWindow(app.UIFigure, 'ccTools.ProgressDialog', 'Em andamento...');

                if ForceAdd2Cache
                    wordCloudIndex = [];
                else
                    relatedTable   = notes_RelatedCacheTable(app, selectedHom);
                    wordCloudIndex = find(strcmp(relatedTable.("Atributo"), 'WordCloud'), 1);
                end

                listOfWordsTable = [];
                if ~isempty(wordCloudIndex)
                    listOfWordsCSV         = relatedTable.("Valor"){wordCloudIndex};
                    listOfWordsTable       = struct2table(regexp(listOfWordsCSV, '\s?(?<Word>[^,:]+): (?<Count>\d+)', 'names'));
                    listOfWordsTable.Count = cellfun(@(x) str2double(x), listOfWordsTable.Count);

                else
                    try
                        word2Search = wordCloud_Word2Search(app, selectedRow, selectedHom);
                    catch ME
                        delete(d)
                        layout.modalWindow(app.UIFigure, 'warning', ME.message);

                        status = false;
                        return
                    end
                    listOfWords = fcn.getRelatedWords(word2Search);

                    if ~isempty(listOfWords)
                        nMaxWords        = str2double(app.notes_WordCloudNumber.Value);
                        listOfWordsTable = wordCloudCounts(listOfWords);

                        if height(listOfWordsTable) > nMaxWords
                            listOfWordsTable = listOfWordsTable(1:nMaxWords,:);
                        end

                        % Adicionar a nova informação ao cache temporário,
                        % substituindo os caracteres especiais "," e ":".
                        listOfWordsCSV = char(strjoin(replace(listOfWordsTable.Word, {',', ':'}, {'_', '_'}) + ": " + listOfWordsTable.Count, ', '));
                        notes_Add2Cache(app, selectedHom, 'WordCloud', listOfWordsCSV, ForceAdd2Cache)
                    end
                end

                if ~isempty(listOfWordsTable)
                    if isempty(app.wordCloudChart)
                        wordCloud_ChartCreation(app)
                    end
                    
                    app.wordCloudChart.SourceTable = listOfWordsTable;
                    app.wordCloudPanel.Tag         = selectedHom;
                    drawnow
                end

                delete(d)
            end
        end


        %-----------------------------------------------------------------%
        function word2Search = wordCloud_Word2Search(app, selectedRow, selectedHom)
            listOfWords = {char(app.search_Table.Data.("Modelo")(selectedRow)), ...
                           char(app.search_Table.Data.("Nome Comercial")(selectedRow))};

            switch app.notes_WordCloudColumn.Value
                case 'Modelo';         idx1 = 1;
                case 'Nome Comercial'; idx1 = 2;
            end
            word2Search = listOfWords{idx1};

            if isempty(word2Search)
                idx2 = setdiff([1 2], idx1);
                word2Search = listOfWords{idx2};

                if isempty(word2Search)
                    error('Registro %s não possui cadastrado "Modelo" ou "Nome Comercial", inviabilizando consulta à internet.', selectedHom)
                else
                    listOfColumns = {'Modelo', 'Nome Comercial'};
                    layout.modalWindow(app.UIFigure, 'warning', sprintf('O registro %s não possui cadastrado "%s". Dessa forma, consulta à internet foi realizada usando o seu "%s".', selectedHom, listOfColumns{idx1}, listOfColumns{idx2}));
                end
            end
        end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            try
                % PATH SEARCH
                app.rootFolder = fileparts(mfilename('fullpath'));

                if isdeployed
                    [~, result]          = system('path');
                    executableFileFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
    
                    if isfile(fullfile(executableFileFolder, sprintf('%s.exe', class.Constants.appName)))
                        app.rootFolder = executableFileFolder;
                    end
                end
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % MOVER ESSES WARNINGS PARA UMA FUNÇÃO DE CCTOOLS %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % WARNING MESSAGES
                warning('off', 'MATLAB:ui:javaframe:PropertyToBeRemoved')
                warning('off', 'MATLAB:subscripting:noSubscriptsSpecified')
                warning('off', 'MATLAB:structOnObject')
                warning('off', 'MATLAB:class:DestructorError')
                warning('off', 'MATLAB:modes:mode:InvalidPropertySet')
                warning('off', 'MATLAB:table:RowsAddedExistingVars')
                warning('off', 'MATLAB:colon:operandsNotRealScalar')

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % TRECHO TEMPORÁRIO RELACIONADO À GUI. REMOVER NA COMPILAÇÃO! %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                app.Grid.ColumnWidth(1:2)   = {0, 0};
                app.Tab1Grid.RowHeight(4:6) = {0, 0, 0};

                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                % TRECHO TEMPORÁRIO RELACIONADO À GUI. REMOVER NA COMPILAÇÃO! %
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                % STARTUP
                layout.winPosition(app)
                jsBackDoor_Initialization(app)
                TimerCreation(app)

            catch ME
                layout.modalWindow(app.UIFigure, 'error', getReport(ME), 'CloseFcn', @(~,~)closeFcn(app));
            end

        end

        % Close request function: UIFigure
        function closeFcn(app, event)

            % DELETE TIMER
            h = timerfindall;
            stop(h); delete(h); clear h

            delete(app)
            
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            
            hitObject = struct(event).HitObject;
            switch hitObject
                case app.search_entryPoint
                    switch app.search_entryPoint.Value
                        case 'O que você quer pesquisar?'
                            set(app.search_entryPoint, 'FontColor', [0,0,0], 'Value', '')

                        otherwise
                            if numel(app.search_entryPoint.Value) > 1
                                app.search_Suggestions.Visible = 1;
                            end
                    end

                case app.search_Suggestions
                    if isempty(app.search_Suggestions.Value)
                        waitfor(app.search_Suggestions, 'Value')
                    end

                    jsBackDoor_keyDownListener(app, struct('HTMLEventData', 'app.search_Suggestions', 'HTMLEventName', 'Enter'))

                otherwise
                    set(app.search_Suggestions, Visible=0, Value={})
                    if isempty(app.search_entryPoint.Value)
                        set(app.search_entryPoint, 'FontColor', [.8,.8,.8], 'Value', 'O que você quer pesquisar?')
                    end                    
            end

        end

        % Image clicked function: menu_btnInfo, menu_btnReport, 
        % ...and 2 other components
        function menuButtonPushed(app, event)
            
            switch event.Source
                case app.menu_btnSearch
                    app.Grid.ColumnWidth(4:5) = {'1x', 0};
                    app.menu_Title.Text = 'SCH > <font style="color:#bfbf13;"><b>Consulta geral</b></font>';
                    layout_OperationModeIconColor(app)
                
                case app.menu_btnReport
                    app.Grid.ColumnWidth(4:5) = {0, '1x'};
                    app.menu_Title.Text = 'SCH > <font style="color:#bfbf13;"><b>Relatório</b></font>';
                    layout_OperationModeIconColor(app)

                case app.menu_btnSettings
                    if app.Grid.ColumnWidth{2}
                        app.menu_btnSettings.ImageSource = 'ArrowRight_32x32W.png';
                        app.Grid.ColumnWidth(1:2) = {0, 0};
                    else
                        app.menu_btnSettings.ImageSource = 'ArrowLeft_32x32W.png';
                        app.Grid.ColumnWidth(1:2) = {5, 330};
                    end
                    focus(app.jsBackDoor)

                case app.menu_btnInfo
                    try
                        [~, SCHLinks] = fcn.PublicLinks(app.rootFolder);
                        web(SCHLinks.Sharepoint)
                    catch ME
                        layout.modalWindow(app.UIFigure, 'error', ME.message);
                    end
            end

        end

        % Image clicked function: menu_Button1Icon, menu_Button2Icon, 
        % ...and 2 other components
        function menuTabSelectionChanged(app, event)
            
            idx = str2double(event.Source.Tag);
            NN  = numel(app.menu_ControlGrid.ColumnWidth);

            for ii = 1:NN
                switch ii
                    case idx
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = "1x";', ii))
                    otherwise
                        eval(sprintf('app.menu_Button%dGrid.ColumnWidth{2} = 0;',    ii))
                end
            end

            columnWidth      = repmat({22}, 1, NN);
            columnWidth(idx) = {'1x'};

            app.menu_ControlGrid.ColumnWidth = columnWidth;
            app.menu_Underline.Layout.Column = idx;
            app.menu_TabGroup.SelectedTab    = app.menu_TabGroup.Children(idx);

            focus(app.jsBackDoor)

        end

        % Value changing function: search_entryPoint
        function EntryPointValueChanging(app, event)

            suggestion_EntryPointValueChanging(app, event.Value, true)

        end

        % Image clicked function: search_entryPointImage
        function EntryPointImageClicked(app, event)
            
            value2Search = fcn.PreProcessedData(app.search_entryPoint.Value);
            words2Search = app.search_Suggestions.Items;
            
            if ~isempty(words2Search)
                nWords2Search  = numel(words2Search);
                nWordsContains = sum(contains(words2Search, value2Search));

                listOfWords    = words2Search(1:min([20, nWords2Search]));
                listOfColumns  = primaryFilter_ListOfColumns(app);

                if nWordsContains < nWords2Search
                    searchNote = ' e similares';
                else
                    searchNote = '';
                end
    
                app.search_Metadata.Text = sprintf('Exibindo resultados para "<b>%s</b>"%s\n<p style="color: #808080; font-size:10px;">Palavras-chave: %s</p>', ...
                                                   app.search_entryPoint.Value, searchNote, jsonencode(struct('Qtd', sprintf('%d de %d', nWordsContains, nWords2Search), 'Amostra', {listOfWords}, 'Coluna', {listOfColumns})));

                layout_TableVisibility(app)
                primaryFilter_Filtering(app, words2Search, listOfColumns)                
            end

        end

        % Selection changed function: search_Table
        function TableSelectionChanged(app, event)
            
            [selectedRow, selectedHom, selectedHomIndex] = notes_SelectedRow(app);

            if ~isempty(selectedRow)
                primaryIndex = app.search_Table.UserData;
                relatedTable = notes_RelatedCacheTable(app, selectedHom);
                app.notes_ProductInfo.HTMLSource = fcn.htmlCode_rowTableInfo(app.rawDataTable(primaryIndex(selectedHomIndex), :), relatedTable);

                % Apresenta a nuvem de palavras apenas se visível...
                if app.Tab1Grid.RowHeight{5}
                    if wordCloud_CheckCache(app, selectedHom, relatedTable)
                        wordCloud_PlotUpdate(app, false);
                    end
                end

                app.annotationPanelVisibility.Enable = 1;
                app.wordCloudVisibility.Enable       = 1;
                app.wordCloudRefresh.Enable          = 1;
                app.notes_addImage.Enable            = 1;

            else
                app.notes_ProductInfo.HTMLSource = 'Warning.html';

                if isa(app.wordCloudChart, 'matlab.graphics.chart.WordCloudChart')
                    app.wordCloudChart.SourceTable = wordCloud_EmptyTable(app);
                    app.wordCloudPanel.Tag         = selectedHom;
                end

                app.annotationPanelVisibility.Enable = 0;
                app.wordCloudVisibility.Enable       = 0;
                app.wordCloudRefresh.Enable          = 0;
                app.notes_addImage.Enable            = 0;
            end
            
        end

        % Selection changed function: filter_PrimaryPanel
        function primaryFilter_ListOfColumnsChanged(app, event)
            
            app.previousSearch = '';

            eventValue = app.search_entryPoint.Value;
            suggestion_EntryPointValueChanging(app, eventValue, false)
            
            EntryPointImageClicked(app)

        end

        % Value changed function: filter_ApplicantCheckBox, 
        % ...and 7 other components
        function secundaryFilter_CheckBoxClicked(app, event)
            
            columnName   = event.Source.Text;
            editfieldObj = findobj(app.filter_SecundaryGrid, 'Tag', columnName);

            switch editfieldObj.Type
                case 'uieditfield'
                    if event.Source.Value
                        set(editfieldObj, 'Enable', 1, 'Value', '')
    
                    else
                        set(editfieldObj, 'Enable', 0, 'Value', '')
                        secundaryFilter_Filtering(app)
                    end

                case 'uidropdown'
                    if event.Source.Value
                        primaryIndex = app.search_Table.UserData;
                        set(editfieldObj, 'Enable', 1, 'Items', [{''}; cellstr(unique(app.rawDataTable{primaryIndex, columnName}))])
    
                    else
                        set(editfieldObj, 'Enable', 0, 'Items', {})
                        secundaryFilter_Filtering(app)
                    end
            end

        end

        % Callback function: filter_Applicant, filter_CNPJ, 
        % ...and 6 other components
        function secundaryFilter_Filtering(app, event)
            
            primaryIndex = app.search_Table.UserData;
            checkboxList = findobj(app.filter_SecundaryGrid, 'Type', 'uicheckbox', 'Value', 1);
            GUIColumns   = class.Constants.GUIColumns;

            if ~isempty(checkboxList)
                columnNames   = arrayfun(@(x) x.Text, checkboxList, 'UniformOutput', false);
                logicalMatrix = zeros(numel(primaryIndex), numel(columnNames), 'logical');
    
                for ii = 1:numel(columnNames)
                    columnName      = columnNames{ii};
                    cacheColumnName = suggestion_FindCache_ColumnName(app, columnName);
                    
                    if ~strcmp(columnName, cacheColumnName)
                        if exist('event', 'var') && strcmp(event.Source.Tag, columnName)
                            value2Search = fcn.PreProcessedData(event.Value);
                        else
                            editfieldObj = findobj(app.filter_SecundaryGrid, 'Tag', columnName);
                            value2Search = fcn.PreProcessedData(editfieldObj.Value);                        
                        end
        
                        logicalMatrix(:,ii) = contains(app.rawDataTable{primaryIndex, cacheColumnName}, value2Search, 'IgnoreCase', true);

                    else
                        if exist('event', 'var') && strcmp(event.Source.Tag, columnName)
                            value2Search = event.Value;
                        else
                            editfieldObj = findobj(app.filter_SecundaryGrid, 'Tag', columnName);
                            value2Search = editfieldObj.Value;                        
                        end

                        if isempty(value2Search)
                            logicalMatrix(:,ii) = true;
                        else
                            if iscategorical(app.rawDataTable{primaryIndex, cacheColumnName})
                                logicalMatrix(:,ii) = strcmpi(cellstr(app.rawDataTable{primaryIndex, cacheColumnName}), value2Search);
                            else
                                logicalMatrix(:,ii) = contains(app.rawDataTable{primaryIndex, cacheColumnName}, value2Search, 'IgnoreCase', true);
                            end
                        end
                    end
                end
                logicalArray  = all(logicalMatrix, 2);
                filteredIndex = primaryIndex(logicalArray);
                    
                app.search_Table.Data = app.rawDataTable(filteredIndex, GUIColumns);

            else
                app.search_Table.Data = app.rawDataTable(primaryIndex, GUIColumns);
            end

            % Renderiza em tela o número de linhas, além de selecionar a primeira 
            % linha da tabela, caso a pesquisa retorne algo.
            layout_TableNumberOfRows(app)
            layout_TableInitialSelection(app)

            % Aplica estilo na tabela...
            layout_TableAddStyle(app)

            drawnow

        end

        % Image clicked function: notes_addImage
        function notes_Add2CacheImageClicked(app, event)
            
            % O valor de um objeto "uitextarea" será sempre um cellstr.
            % O arranjo a seguir elimina linhas vazias, concatenando as
            % outras.
            app.notes_Value.Value = strtrim(app.notes_Value.Value);
            app.notes_Value.Value(cellfun(@(x) isempty(x), app.notes_Value.Value)) = [];
            app.notes_Value.Value = strjoin(app.notes_Value.Value, ' ');

            selectedRow    = app.search_Table.Selection;
            selectedHom    = app.search_Table.Data.("Homologação"){selectedRow};
            
            attributeName  = app.notes_Attribute.Value;
            attributeValue = char(app.notes_Value.Value);

            if ~isempty(attributeValue)
                notes_Add2Cache(app, selectedHom, attributeName, attributeValue, false)

            else
                layout.modalWindow(app.UIFigure, 'warning', 'Deve ser informado o valor do atributo.');
            end

        end

        % Image clicked function: annotationPanelVisibility, 
        % ...and 1 other component
        function notes_PanelVisibilityControl(app, event)
            
            switch event.Source
                case app.annotationPanelVisibility
                    if app.Tab1Grid.RowHeight{4}
                        app.annotationPanelVisibility.ImageSource = 'Edit_18x18G.png';
                        app.Tab1Grid.RowHeight{4} = 0;
                    else
                        app.annotationPanelVisibility.ImageSource = 'Edit_18x18B.png';
                        app.Tab1Grid.RowHeight{4} = 160;
                    end

                case app.wordCloudVisibility
                    if app.Tab1Grid.RowHeight{5}
                        app.wordCloudVisibility.ImageSource = 'Cloud_32x32G.png';
                        app.Tab1Grid.RowHeight(5:6) = {0, 0};
                    else
                        app.wordCloudVisibility.ImageSource = 'Cloud_32x32B.png';
                        app.Tab1Grid.RowHeight(5:6) = {133, 22};

                        [~, selectedHom] = notes_SelectedRow(app);
                        relatedTable     = notes_RelatedCacheTable(app, selectedHom);

                        if wordCloud_CheckCache(app, selectedHom, relatedTable)
                            wordCloud_PlotUpdate(app, false);
                        end
                    end
            end

            focus(app.search_Table)

        end

        % Value changed function: notes_WordCloudNumber
        function wordCloud_NumberOfWrodsValueChanged(app, event)
            
            if ~isempty(app.wordCloudChart)
                app.wordCloudChart.MaxDisplayWords = str2double(app.notes_WordCloudNumber.Value);
            end

        end

        % Image clicked function: wordCloudRefresh
        function wordCloud_RefreshImageClicked(app, event)
            
            % Primeiro tenta atualizar a nuvem de palavras...
            status = wordCloud_PlotUpdate(app, true);

            if status
                % E se a atualização tiver sido bem sucedida, apaga os
                % registros antigos do cache. Como o novo registro será
                % armazenado em "app.annotationTempTable", mantém-se apenas 
                % o seu último registro.
                [~, selectedHom] = notes_SelectedRow(app);

                idx1 = find(strcmp(app.annotationTable.("Homologação"),     selectedHom) & strcmp(app.annotationTable.("Atributo"),     'WordCloud'));
                idx2 = find(strcmp(app.annotationTempTable.("Homologação"), selectedHom) & strcmp(app.annotationTempTable.("Atributo"), 'WordCloud'));

                app.annotationTable(idx1, :)              = [];
                app.annotationTempTable(idx2(1:end-1), :) = [];
            end

            focus(app.search_Table)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'SCH';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'Icons', 'Icon_36x36.png');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.Grid = uigridlayout(app.UIFigure);
            app.menu_Grid = uigridlayout(app.Grid);
            app.menu_Title = uilabel(app.menu_Grid);
            app.menu_Title.Interpreter = 'html';
            app.menu_Title.Text = 'SCH > <font style="color:#f0f022;"><b>Consulta geral</b></font>';
            app.menu_btnSettings = uiimage(app.menu_Grid);
            app.menu_btnSettings.ImageClickedFcn = createCallbackFcn(app, @menuButtonPushed, true);
            app.menu_btnSettings.Visible = 'off';
            app.menu_btnSettings.ImageSource = fullfile(pathToMLAPP, 'Icons', 'ArrowLeft_32x32W.png');
            app.menu_btnSearch = uiimage(app.menu_Grid);
            app.menu_btnSearch.ImageClickedFcn = createCallbackFcn(app, @menuButtonPushed, true);
            app.menu_btnSearch.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Zoom_36x36Y.png');
            app.menu_btnReport = uiimage(app.menu_Grid);
            app.menu_btnReport.ImageClickedFcn = createCallbackFcn(app, @menuButtonPushed, true);
            app.menu_btnReport.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Report_36x36W.png');
            app.menu_btnInfo = uiimage(app.menu_Grid);
            app.menu_btnInfo.ImageClickedFcn = createCallbackFcn(app, @menuButtonPushed, true);
            app.menu_btnInfo.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Info_36x36W.png');
            app.jsBackDoor = uihtml(app.menu_Grid);
            app.menu_TabGroupGrid = uigridlayout(app.Grid);
            app.menu_TabGroup = uitabgroup(app.menu_TabGroupGrid);
            app.Tab1 = uitab(app.menu_TabGroup);
            app.Tab1Grid = uigridlayout(app.Tab1);
            app.notes_Label1 = uilabel(app.Tab1Grid);
            app.notes_Label1.Text = 'PRODUTO SELECIONADO';
            app.notes_Panel1 = uipanel(app.Tab1Grid);
            app.notes_Panel1Grid = uigridlayout(app.notes_Panel1);
            app.notes_ProductInfo = uihtml(app.notes_Panel1Grid);
            app.notes_ProductInfo.HTMLSource = 'Warning.html';
            app.GridLayout2 = uigridlayout(app.Tab1Grid);
            app.wordCloudVisibility = uiimage(app.GridLayout2);
            app.wordCloudVisibility.ImageClickedFcn = createCallbackFcn(app, @notes_PanelVisibilityControl, true);
            app.wordCloudVisibility.Enable = 'off';
            app.wordCloudVisibility.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Cloud_32x32G.png');
            app.annotationPanelVisibility = uiimage(app.GridLayout2);
            app.annotationPanelVisibility.ImageClickedFcn = createCallbackFcn(app, @notes_PanelVisibilityControl, true);
            app.annotationPanelVisibility.Enable = 'off';
            app.annotationPanelVisibility.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Edit_18x18G.png');
            app.notes_Panel2 = uipanel(app.Tab1Grid);
            app.notes_Panel2Grid = uigridlayout(app.notes_Panel2);
            app.notes_AttributeLabel = uilabel(app.notes_Panel2Grid);
            app.notes_AttributeLabel.Text = 'Atributo:';
            app.notes_Attribute = uidropdown(app.notes_Panel2Grid);
            app.notes_Attribute.Items = {'Fornecedor', 'Fabricante', 'Modelo', 'Outras informações'};
            app.notes_Attribute.Value = 'Fornecedor';
            app.notes_ValueLabel = uilabel(app.notes_Panel2Grid);
            app.notes_ValueLabel.Text = 'Valor:';
            app.notes_Value = uitextarea(app.notes_Panel2Grid);
            app.notes_addImage = uiimage(app.notes_Panel2Grid);
            app.notes_addImage.ImageClickedFcn = createCallbackFcn(app, @notes_Add2CacheImageClicked, true);
            app.notes_addImage.Enable = 'off';
            app.notes_addImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Add_18x18.png');
            app.wordCloudPanel = uipanel(app.Tab1Grid);
            app.GridLayout3 = uigridlayout(app.Tab1Grid);
            app.wordCloudRefresh = uiimage(app.GridLayout3);
            app.wordCloudRefresh.ImageClickedFcn = createCallbackFcn(app, @wordCloud_RefreshImageClicked, true);
            app.wordCloudRefresh.Enable = 'off';
            app.wordCloudRefresh.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Refresh_18x18G.png');
            app.Tab2 = uitab(app.menu_TabGroup);
            app.Tab2Grid = uigridlayout(app.Tab2);
            app.filter_PrimaryPanelLabel = uilabel(app.Tab2Grid);
            app.filter_PrimaryPanelLabel.Text = 'PRIMÁRIA';
            app.filter_PrimaryPanel = uibuttongroup(app.Tab2Grid);
            app.filter_PrimaryPanel.SelectionChangedFcn = createCallbackFcn(app, @primaryFilter_ListOfColumnsChanged, true);
            app.filter_PrimaryOption1 = uiradiobutton(app.filter_PrimaryPanel);
            app.filter_PrimaryOption1.Text = 'Homologação';
            app.filter_PrimaryOption2 = uiradiobutton(app.filter_PrimaryPanel);
            app.filter_PrimaryOption2.Text = 'Solicitante | Fabricante';
            app.filter_PrimaryOption3 = uiradiobutton(app.filter_PrimaryPanel);
            app.filter_PrimaryOption3.Text = 'Modelo | Nome Comercial';
            app.filter_PrimaryOption3.Value = true;
            app.filter_SecundaryPanelLabel = uilabel(app.Tab2Grid);
            app.filter_SecundaryPanelLabel.Text = 'SECUNDÁRIA';
            app.filter_SecundaryPanel = uipanel(app.Tab2Grid);
            app.filter_SecundaryGrid = uigridlayout(app.filter_SecundaryPanel);
            app.filter_CertificationCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_CertificationCheckBox.ValueChangedFcn = createCallbackFcn(app, @secundaryFilter_CheckBoxClicked, true);
            app.filter_CertificationCheckBox.Enable = 'off';
            app.filter_CertificationCheckBox.Text = 'Homologação';
            app.filter_Certification = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_Certification.ValueChangingFcn = createCallbackFcn(app, @secundaryFilter_Filtering, true);
            app.filter_Certification.Tag = 'Homologação';
            app.filter_Certification.Enable = 'off';
            app.filter_ProductListCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_ProductListCheckBox.ValueChangedFcn = createCallbackFcn(app, @secundaryFilter_CheckBoxClicked, true);
            app.filter_ProductListCheckBox.Enable = 'off';
            app.filter_ProductListCheckBox.Text = 'Tipo';
            app.filter_ProductList = uidropdown(app.filter_SecundaryGrid);
            app.filter_ProductList.Items = {''};
            app.filter_ProductList.ValueChangedFcn = createCallbackFcn(app, @secundaryFilter_Filtering, true);
            app.filter_ProductList.Tag = 'Tipo';
            app.filter_ProductList.Enable = 'off';
            app.filter_ProductList.Value = '';
            app.filter_ApplicantCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_ApplicantCheckBox.ValueChangedFcn = createCallbackFcn(app, @secundaryFilter_CheckBoxClicked, true);
            app.filter_ApplicantCheckBox.Enable = 'off';
            app.filter_ApplicantCheckBox.Text = 'Solicitante';
            app.filter_Applicant = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_Applicant.ValueChangingFcn = createCallbackFcn(app, @secundaryFilter_Filtering, true);
            app.filter_Applicant.Tag = 'Solicitante';
            app.filter_Applicant.Enable = 'off';
            app.filter_CNPJCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_CNPJCheckBox.ValueChangedFcn = createCallbackFcn(app, @secundaryFilter_CheckBoxClicked, true);
            app.filter_CNPJCheckBox.Enable = 'off';
            app.filter_CNPJCheckBox.Text = 'CNPJ/CPF';
            app.filter_CNPJ = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_CNPJ.ValueChangingFcn = createCallbackFcn(app, @secundaryFilter_Filtering, true);
            app.filter_CNPJ.Tag = 'CNPJ/CPF';
            app.filter_CNPJ.Enable = 'off';
            app.filter_ManufacturerCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_ManufacturerCheckBox.ValueChangedFcn = createCallbackFcn(app, @secundaryFilter_CheckBoxClicked, true);
            app.filter_ManufacturerCheckBox.Enable = 'off';
            app.filter_ManufacturerCheckBox.Text = 'Fabricante';
            app.filter_Manufacturer = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_Manufacturer.ValueChangingFcn = createCallbackFcn(app, @secundaryFilter_Filtering, true);
            app.filter_Manufacturer.Tag = 'Fabricante';
            app.filter_Manufacturer.Enable = 'off';
            app.filter_ModelCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_ModelCheckBox.ValueChangedFcn = createCallbackFcn(app, @secundaryFilter_CheckBoxClicked, true);
            app.filter_ModelCheckBox.Enable = 'off';
            app.filter_ModelCheckBox.Text = 'Modelo';
            app.filter_Model = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_Model.ValueChangingFcn = createCallbackFcn(app, @secundaryFilter_Filtering, true);
            app.filter_Model.Tag = 'Modelo';
            app.filter_Model.Enable = 'off';
            app.filter_TradeNameCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_TradeNameCheckBox.ValueChangedFcn = createCallbackFcn(app, @secundaryFilter_CheckBoxClicked, true);
            app.filter_TradeNameCheckBox.Enable = 'off';
            app.filter_TradeNameCheckBox.Text = 'Nome Comercial';
            app.filter_TradeName = uieditfield(app.filter_SecundaryGrid, 'text');
            app.filter_TradeName.ValueChangingFcn = createCallbackFcn(app, @secundaryFilter_Filtering, true);
            app.filter_TradeName.Tag = 'Nome Comercial';
            app.filter_TradeName.Enable = 'off';
            app.filter_StatusCheckBox = uicheckbox(app.filter_SecundaryGrid);
            app.filter_StatusCheckBox.ValueChangedFcn = createCallbackFcn(app, @secundaryFilter_CheckBoxClicked, true);
            app.filter_StatusCheckBox.Enable = 'off';
            app.filter_StatusCheckBox.Text = 'Situação';
            app.filter_Status = uidropdown(app.filter_SecundaryGrid);
            app.filter_Status.Items = {''};
            app.filter_Status.ValueChangedFcn = createCallbackFcn(app, @secundaryFilter_Filtering, true);
            app.filter_Status.Tag = 'Situação';
            app.filter_Status.Enable = 'off';
            app.filter_Status.Value = '';
            app.Tab3 = uitab(app.menu_TabGroup);
            app.Tab3Grid = uigridlayout(app.Tab3);
            app.report_TreeLabel = uilabel(app.Tab3Grid);
            app.report_TreeLabel.Text = 'PRODUTO SELECIONADO';
            app.report_SelectedProductLabel = uilabel(app.Tab3Grid);
            app.report_SelectedProductLabel.Text = 'EVIDÊNCIAS';
            app.report_SelectedProductPanel = uipanel(app.Tab3Grid);
            app.report_SelectedProductGrid = uigridlayout(app.report_SelectedProductPanel);
            app.report_TypeLabel = uilabel(app.report_SelectedProductGrid);
            app.report_TypeLabel.Text = 'Tipo:';
            app.report_Type = uidropdown(app.report_SelectedProductGrid);
            app.report_Type.Items = {''};
            app.report_Type.Value = '';
            app.report_QuantityLabel = uilabel(app.report_SelectedProductGrid);
            app.report_QuantityLabel.Text = 'Quantidade:';
            app.report_Quantity = uieditfield(app.report_SelectedProductGrid, 'numeric');
            app.report_MonetaryValueLabel = uilabel(app.report_SelectedProductGrid);
            app.report_MonetaryValueLabel.Text = 'Valor unitário (R$):';
            app.report_MonetaryValue = uieditfield(app.report_SelectedProductGrid, 'numeric');
            app.report_ComplementaryNotesLabel = uilabel(app.report_SelectedProductGrid);
            app.report_ComplementaryNotesLabel.Text = 'Informações complementares:';
            app.report_ComplementaryNotes = uitextarea(app.report_SelectedProductGrid);
            app.report_TypeLabel_2 = uilabel(app.report_SelectedProductGrid);
            app.report_TypeLabel_2.Text = 'Situação:';
            app.report_Type_2 = uidropdown(app.report_SelectedProductGrid);
            app.report_Type_2.Items = {'', 'REGULAR', 'INDÍCIO DE IRREGULARIDADE'};
            app.report_Type_2.Value = '';
            app.report_addImage = uiimage(app.Tab3Grid);
            app.report_addImage.Enable = 'off';
            app.report_addImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Add_18x18.png');
            app.report_Tree = uitree(app.Tab3Grid);
            app.Tab4 = uitab(app.menu_TabGroup);
            app.Tab4Grid = uigridlayout(app.Tab4);
            app.config_Label = uilabel(app.Tab4Grid);
            app.config_Label.Text = 'ASPECTOS GERAIS';
            app.config_Panel = uipanel(app.Tab4Grid);
            app.config_PanelGrid = uigridlayout(app.config_Panel);
            app.config_FolderLabel = uilabel(app.config_PanelGrid);
            app.config_FolderLabel.Text = 'Pasta de trabalho:';
            app.config_FolderGrid = uigridlayout(app.config_PanelGrid);
            app.config_Folder = uidropdown(app.config_FolderGrid);
            app.config_Folder.Items = {};
            app.config_Folder.Value = {};
            app.config_FolderButton = uibutton(app.config_FolderGrid, 'push');
            app.config_FolderButton.Icon = fullfile(pathToMLAPP, 'Icons', 'OpenFile_36x36.png');
            app.config_FolderButton.Text = '';
            app.config_SuggestionLabel = uilabel(app.config_PanelGrid);
            app.config_SuggestionLabel.Text = 'Algoritmo de sugestão de palavras-chave:';
            app.config_SuggestionPanel = uipanel(app.config_PanelGrid);
            app.config_SuggestionGrid = uigridlayout(app.config_SuggestionPanel);
            app.config_nCharactersLabel = uilabel(app.config_SuggestionGrid);
            app.config_nCharactersLabel.Text = {'Qtd. mínima'; 'caracteres:'};
            app.config_nCharacters = uispinner(app.config_SuggestionGrid);
            app.config_nCharacters.Limits = [2 4];
            app.config_nCharacters.RoundFractionalValues = 'on';
            app.config_nCharacters.ValueDisplayFormat = '%d';
            app.config_nCharacters.Value = 2;
            app.config_nMinWordsLabel = uilabel(app.config_SuggestionGrid);
            app.config_nMinWordsLabel.Text = {'Qtd. mínima '; 'palavras-chave:'};
            app.config_nMinWords = uidropdown(app.config_SuggestionGrid);
            app.config_nMinWords.Items = {'10', '20', '40'};
            app.config_nMinWords.Value = '10';
            app.config_htmlListView_2 = uicheckbox(app.config_PanelGrid);
            app.config_htmlListView_2.Text = 'Considerar anotações na busca primária textual.';
            app.notes_WordCloudLabel = uilabel(app.config_PanelGrid);
            app.notes_WordCloudLabel.Text = 'Nuvem de palavras:';
            app.notes_WordCloudPanel = uipanel(app.config_PanelGrid);
            app.notes_WordCloudGrid = uigridlayout(app.notes_WordCloudPanel);
            app.notes_WordCloudNumberLabel = uilabel(app.notes_WordCloudGrid);
            app.notes_WordCloudNumberLabel.Text = {'Qtd. máxima'; 'palavras:'};
            app.notes_WordCloudNumber = uidropdown(app.notes_WordCloudGrid);
            app.notes_WordCloudNumber.Items = {'10', '25', '50'};
            app.notes_WordCloudNumber.ValueChangedFcn = createCallbackFcn(app, @wordCloud_NumberOfWrodsValueChanged, true);
            app.notes_WordCloudNumber.Value = '25';
            app.notes_WordCloudColumnLabel = uilabel(app.notes_WordCloudGrid);
            app.notes_WordCloudColumnLabel.Text = {'Busca de palavras '; 'orientada à coluna:'};
            app.notes_WordCloudColumn = uidropdown(app.notes_WordCloudGrid);
            app.notes_WordCloudColumn.Items = {'Modelo', 'Nome Comercial'};
            app.notes_WordCloudColumn.Value = 'Modelo';
            app.menu_ControlGrid = uigridlayout(app.menu_TabGroupGrid);
            app.menu_Underline = uiimage(app.menu_ControlGrid);
            app.menu_Underline.ImageSource = fullfile(pathToMLAPP, 'Icons', 'LineH.png');
            app.menu_Button1Grid = uigridlayout(app.menu_ControlGrid);
            app.menu_Button1Label = uilabel(app.menu_Button1Grid);
            app.menu_Button1Label.Text = 'Aspectos gerais';
            app.menu_Button1Icon = uiimage(app.menu_Button1Grid);
            app.menu_Button1Icon.ImageClickedFcn = createCallbackFcn(app, @menuTabSelectionChanged, true);
            app.menu_Button1Icon.Tag = '1';
            app.menu_Button1Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Connect_18x18.png');
            app.menu_Button2Grid = uigridlayout(app.menu_ControlGrid);
            app.menu_Button2Label = uilabel(app.menu_Button2Grid);
            app.menu_Button2Label.Text = 'Filtragem';
            app.menu_Button2Icon = uiimage(app.menu_Button2Grid);
            app.menu_Button2Icon.ImageClickedFcn = createCallbackFcn(app, @menuTabSelectionChanged, true);
            app.menu_Button2Icon.Tag = '2';
            app.menu_Button2Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Filter_18x18.png');
            app.menu_Button3Grid = uigridlayout(app.menu_ControlGrid);
            app.menu_Button3Label = uilabel(app.menu_Button3Grid);
            app.menu_Button3Label.Text = 'Evidências da comercialização';
            app.menu_Button3Icon = uiimage(app.menu_Button3Grid);
            app.menu_Button3Icon.ImageClickedFcn = createCallbackFcn(app, @menuTabSelectionChanged, true);
            app.menu_Button3Icon.Tag = '3';
            app.menu_Button3Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Money_18x18.png');
            app.menu_Button4Grid = uigridlayout(app.menu_ControlGrid);
            app.menu_Button4Label = uilabel(app.menu_Button4Grid);
            app.menu_Button4Label.Text = 'Configurações';
            app.menu_Button4Icon = uiimage(app.menu_Button4Grid);
            app.menu_Button4Icon.ImageClickedFcn = createCallbackFcn(app, @menuTabSelectionChanged, true);
            app.menu_Button4Icon.Tag = '4';
            app.menu_Button4Icon.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Dots_18x18.png');
            app.search_mainGrid = uigridlayout(app.Grid);
            app.search_entryPointPanel = uipanel(app.search_mainGrid);
            app.search_entryPointGrid = uigridlayout(app.search_entryPointPanel);
            app.search_entryPoint = uieditfield(app.search_entryPointGrid, 'text');
            app.search_entryPoint.CharacterLimits = [0 128];
            app.search_entryPoint.ValueChangingFcn = createCallbackFcn(app, @EntryPointValueChanging, true);
            app.search_entryPoint.Tag = 'PROMPT';
            app.search_entryPoint.Enable = 'off';
            app.search_entryPoint.Value = 'O que você quer pesquisar?';
            app.search_entryPointImage = uiimage(app.search_entryPointGrid);
            app.search_entryPointImage.ImageClickedFcn = createCallbackFcn(app, @EntryPointImageClicked, true);
            app.search_entryPointImage.Enable = 'off';
            app.search_entryPointImage.ImageSource = fullfile(pathToMLAPP, 'Icons', 'Zoom_36x36.png');
            app.search_Metadata = uilabel(app.search_mainGrid);
            app.search_Metadata.Visible = 'off';
            app.search_Metadata.Interpreter = 'html';
            app.search_Metadata.Text = {'Exibindo resultados para "<b>apple iphone</b>"'; '<p style="color: #808080; font-size:10px;">Pesquisa realizada nas colunas "Nome do Produto" e "Modelo Comercial".</p><p style="color: #808080; font-size:10px;">Pesquisa realizada nas colunas "Nome do Produto" e "Modelo Comercial".</p>'};
            app.search_Table = uitable(app.search_mainGrid);
            app.search_Table.ColumnName = {'HOMOLOGAÇÃO'; 'TIPO'; 'SOLICITANTE'; 'CNPJ/CPF'; 'FABRICANTE'; 'MODELO'; 'NOME COMERCIAL'; 'SITUAÇÃO'};
            app.search_Table.RowName = {};
            app.search_Table.SelectionType = 'row';
            app.search_Table.SelectionChangedFcn = createCallbackFcn(app, @TableSelectionChanged, true);
            app.search_Table.Multiselect = 'off';
            app.search_Table.Visible = 'off';
            app.search_Suggestions = uilistbox(app.search_mainGrid);
            app.search_Suggestions.Items = {''};
            app.search_Suggestions.Tag = 'CAIXA DE BUSCA';
            app.search_Suggestions.Visible = 'off';
            app.search_Suggestions.Value = {};
            app.search_nRows = uilabel(app.search_mainGrid);
            app.search_nRows.Visible = 'off';
            app.search_nRows.Text = '# 0 ';
            app.report_mainGrid = uigridlayout(app.Grid);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winSCH

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end